classdef pi_pid_design_tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        PITab                         matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        TEditFieldLabel               matlab.ui.control.Label
        TEditField                    matlab.ui.control.NumericEditField
        GEditField_2Label             matlab.ui.control.Label
        GEditField_2                  matlab.ui.control.NumericEditField
        T0EditFieldLabel              matlab.ui.control.Label
        T0EditField                   matlab.ui.control.NumericEditField
        SolveButton                   matlab.ui.control.Button
        KLabel                        matlab.ui.control.Label
        TiLabel                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        PIDTab                        matlab.ui.container.Tab
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes2_2                     matlab.ui.control.UIAxes
        SolveButton_2                 matlab.ui.control.Button
        KLabel_2                      matlab.ui.control.Label
        TiLabel_2                     matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        b0EditFieldLabel              matlab.ui.control.Label
        b0EditField                   matlab.ui.control.NumericEditField
        a1EditFieldLabel              matlab.ui.control.Label
        a1EditField                   matlab.ui.control.NumericEditField
        a2EditFieldLabel              matlab.ui.control.Label
        a2EditField                   matlab.ui.control.NumericEditField
        DesiredmaximumovershootLabel  matlab.ui.control.Label
        DesiredmaximumovershootEditField  matlab.ui.control.NumericEditField
        DesiredrisetimeLabel          matlab.ui.control.Label
        DesiredrisetimeEditField      matlab.ui.control.NumericEditField
        TdLabel                       matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        TdNLabel                      matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            G = app.GEditField_2.Value;
            T=app.T0EditField.Value;
            T0=app.T0EditField.Value;
            app.Label_3.Text=string(T/(G*T0)); %k for pi
            app.Label_4.Text=string(T); %ti for piu
            s =tf('s');
            plant = G/(1+s*T);
            sys = 1/(1+(s*T0));
            %f1=figure;
            [y,t] = step(feedback(plant,1));
            plot(app.UIAxes, t, y);
            %stepplot(app.UIAxes, feedback(plant,1));
            %f2=figure;
            %stepplot(app.UIAxes2, sys);
            [y,t] = step(sys);
            plot(app.UIAxes2, t, y)
        end

        % Button pushed function: SolveButton_2
        function SolveButton_2Pushed(app, event)
            s =tf('s');
            Mp=app.DesiredmaximumovershootEditField.Value / 100;
            Tr=app.DesiredrisetimeEditField.Value;
            zeta=-log(Mp)/sqrt(pi*pi-log(Mp));
            w0=(pi-acos(zeta))/(Tr*sqrt(1-zeta*zeta));
            a1=app.a1EditField.Value;
            a2=app.a2EditField.Value;
            b0=app.b0EditField.Value;
            ratio=(1/(2*zeta*w0));
            Ti = a1-ratio;
            Td = (a2/Ti)-ratio;
            k = (w0*Ti)/(2*zeta*b0);
            app.Label_5.Text=string(Ti); %ti output
            app.Label_6.Text=string(Td); %td output
            app.Label_2.Text=string(k); %k output
            n=Td/ratio;
            app.Label_7.Text = string(ratio);%td/n output
            plant = b0/(1+(a1*s)+(a2*s*s));
            pid=k*(1+(Ti+ratio)*s+((Ti*Td)+(Ti*ratio))*(s*s))/((Ti*s)+(Ti*ratio)*(s*s));
            syst=plant*pid;
            %sys=(w0*w0)/((w0*w0)+(2*zeta*w0*s)+(s*s));
            %fefiplant=figure;
            [y,t] = step(feedback(plant,1));
            plot(app.UIAxes_2, t, y);
            %fefisys=figure;
            %stepplot(sys);
            %fefisyst=figure;
            [y,t] = step(feedback(syst,1));
            plot(app.UIAxes2_2, t, y);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PITab = uitab(app.TabGroup);
            app.PITab.Title = '        PI     ';
            app.UIAxes = uiaxes(app.PITab);
            title(app.UIAxes, 'Plant without controller with feedback')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Output')
            app.UIAxes.YTick = [0 0.5 1];
            app.UIAxes.YTickLabel = {'0'; '0.5'; '1'};
            app.UIAxes2 = uiaxes(app.PITab);
            title(app.UIAxes2, 'Plant with PI controller with feedback')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Output')
            app.UIAxes2.YTick = [0 0.5 1];
            app.UIAxes2.YTickLabel = {'0'; '0.5'; '1'};
            app.TEditFieldLabel = uilabel(app.PITab);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.PITab, 'numeric');
            app.GEditField_2Label = uilabel(app.PITab);
            app.GEditField_2Label.Text = 'G';
            app.GEditField_2 = uieditfield(app.PITab, 'numeric');
            app.T0EditFieldLabel = uilabel(app.PITab);
            app.T0EditFieldLabel.Text = 'T0';
            app.T0EditField = uieditfield(app.PITab, 'numeric');
            app.SolveButton = uibutton(app.PITab, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.KLabel = uilabel(app.PITab);
            app.KLabel.Text = 'K = ';
            app.TiLabel = uilabel(app.PITab);
            app.TiLabel.Text = 'Ti = ';
            app.Label_3 = uilabel(app.PITab);
            app.Label_3.Text = '0';
            app.Label_4 = uilabel(app.PITab);
            app.Label_4.Text = '0';
            app.PIDTab = uitab(app.TabGroup);
            app.PIDTab.Title = '      PID   ';
            app.UIAxes_2 = uiaxes(app.PIDTab);
            title(app.UIAxes_2, 'Plant without controller and with feedback')
            xlabel(app.UIAxes_2, 'Time (s)')
            ylabel(app.UIAxes_2, 'Output')
            app.UIAxes2_2 = uiaxes(app.PIDTab);
            title(app.UIAxes2_2, 'Plant with PID controller with feedback')
            xlabel(app.UIAxes2_2, 'Time (s)')
            ylabel(app.UIAxes2_2, 'Output')
            app.SolveButton_2 = uibutton(app.PIDTab, 'push');
            app.SolveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SolveButton_2Pushed, true);
            app.SolveButton_2.Text = 'Solve';
            app.KLabel_2 = uilabel(app.PIDTab);
            app.KLabel_2.Text = 'K = ';
            app.TiLabel_2 = uilabel(app.PIDTab);
            app.TiLabel_2.Text = 'Ti = ';
            app.Label_2 = uilabel(app.PIDTab);
            app.Label_2.Text = '0';
            app.Label_5 = uilabel(app.PIDTab);
            app.Label_5.Text = '0';
            app.b0EditFieldLabel = uilabel(app.PIDTab);
            app.b0EditFieldLabel.Text = 'b0';
            app.b0EditField = uieditfield(app.PIDTab, 'numeric');
            app.a1EditFieldLabel = uilabel(app.PIDTab);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1EditField = uieditfield(app.PIDTab, 'numeric');
            app.a2EditFieldLabel = uilabel(app.PIDTab);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2EditField = uieditfield(app.PIDTab, 'numeric');
            app.DesiredmaximumovershootLabel = uilabel(app.PIDTab);
            app.DesiredmaximumovershootLabel.Text = {'Desired maximum'; 'overshoot (%)'};
            app.DesiredmaximumovershootEditField = uieditfield(app.PIDTab, 'numeric');
            app.DesiredrisetimeLabel = uilabel(app.PIDTab);
            app.DesiredrisetimeLabel.Text = 'Desired rise time';
            app.DesiredrisetimeEditField = uieditfield(app.PIDTab, 'numeric');
            app.TdLabel = uilabel(app.PIDTab);
            app.TdLabel.Text = 'Td = ';
            app.Label_6 = uilabel(app.PIDTab);
            app.Label_6.Text = '0';
            app.TdNLabel = uilabel(app.PIDTab);
            app.TdNLabel.Text = 'Td/N = ';
            app.Label_7 = uilabel(app.PIDTab);
            app.Label_7.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pi_pid_design_tool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end